classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure            matlab.ui.Figure
        TextArea_7Label_9   matlab.ui.control.Label
        InChannel           matlab.ui.control.Label
        Label_10            matlab.ui.control.Label
        Feature_Channel     matlab.ui.control.TextArea
        TextArea_7Label_8   matlab.ui.control.Label
        SimWorkSpace        matlab.ui.control.Label
        Save_Cfg            matlab.ui.control.Button
        WorkSpaceButton     matlab.ui.control.Button
        WorkSpaceButton_2   matlab.ui.control.Button
        TabGroup            matlab.ui.container.TabGroup
        Tab                 matlab.ui.container.Tab
        Create_Bin_All      matlab.ui.control.Button
        Create_txt_Quant    matlab.ui.control.Button
        Create_Bin_Quant    matlab.ui.control.Button
        Create_txt_Weight   matlab.ui.control.Button
        Create_txt_Picture  matlab.ui.control.Button
        Create_Bin_Weight   matlab.ui.control.Button
        Create_Bin_Picture  matlab.ui.control.Button
        Tab_2               matlab.ui.container.Tab
        Button_2            matlab.ui.control.Button
        TextArea_12         matlab.ui.control.TextArea
        Tab_4               matlab.ui.container.Tab
        Button_3            matlab.ui.control.Button
        TextArea_13         matlab.ui.control.TextArea
        Tab_5               matlab.ui.container.Tab
        TextArea_14         matlab.ui.control.TextArea
        Button_4            matlab.ui.control.Button
        BinButton           matlab.ui.control.Button
        Tab_6               matlab.ui.container.Tab
        Button_9            matlab.ui.control.Button
        Button_8            matlab.ui.control.Button
        Button_6            matlab.ui.control.Button
        Tab_7               matlab.ui.container.Tab
        TextArea_15         matlab.ui.control.TextArea
        Button_10           matlab.ui.control.Button
        TextArea_7Label_6   matlab.ui.control.Label
        TextArea_7Label_5   matlab.ui.control.Label
        TextArea_7Label_4   matlab.ui.control.Label
        Width               matlab.ui.control.TextArea
        Tile                matlab.ui.control.TextArea
        TextArea_7Label_3   matlab.ui.control.Label
        Label_8             matlab.ui.control.Label
        Feature_Size        matlab.ui.control.TextArea
        TextArea_7Label_2   matlab.ui.control.Label
        TextArea_7Label     matlab.ui.control.Label
        Label_7             matlab.ui.control.Label
        appKernelSize       matlab.ui.control.TextArea
        Label_6             matlab.ui.control.Label
        Label_5             matlab.ui.control.Label
        Label_4             matlab.ui.control.Label
        Stride              matlab.ui.control.TextArea
        Out_Channel         matlab.ui.control.TextArea
        Button              matlab.ui.control.Button
        Image               matlab.ui.control.Image
    end

    
    methods (Access = private)
        
       
    % 保存按钮的回调函数
        function saveButtonCallback(app,configDialog,kernelSize)
            % 在保存按钮的回调函数中处理保存操作
    
            % 保存卷积核大小的数据
            showintxt(app,['保存的卷积核大小为: ' num2str(kernelSize)]);
%             SystemLog
            % 关闭配置界面对话框
            delete(configDialog);

        end
        function showintxt(app,txt)
            app.TextArea.Value=txt;
        end


        %% 
        function [Error,Error_Min,Error_Max] = AbsError(Correct,x)%绝对误差的统计
    Error=abs(Correct-x);
    Correct=reshape(Correct,1,[]);%展平
    x=reshape(x,1,[]);
    
    % 第一步：检查最大误差和最小误差
    
    Fpga_Error_Max=max(Error,[],'all');
    Fpga_Error_Min=min(Error,[],'all');
    Error_Min=Fpga_Error_Min;
    Error_Max=Fpga_Error_Max;

    nums=size(Fpga_Error_Min:0.01:Fpga_Error_Max,2)
    Error_Range=Fpga_Error_Min:0.01:Fpga_Error_Max;
    Error_Range=Error_Range(2:end);
    k=1;
    for i=Fpga_Error_Min:0.01:Fpga_Error_Max
        
        nums(k)=sum(Error<i,'all');
        k=k+1;
    end
    nums=nums(2:end)

    %然后再统计每个区间内的数据有多少
    for i=size(nums,2):-1:2
        nums(i)=nums(i)-nums(i-1)
    end
    %需要将少于1%的全部加起来
    Percentage=nums/sum(nums);
    Mask=Percentage<0.01
    if sum(Mask)~=0
        Error_Range=[Error_Range((1:length(nums)-sum(Mask))),Error_Range(end)]
        nums=[nums(1:length(nums)-sum(Mask)),sum(nums.*Mask)];
    end
    %% 

    bar(nums);
    ylabel("Counts")
    xlabel("Error")
    set(gca, 'XTick', 1:length(nums), 'XTickLabel', round(Error_Range,4))
%     xtickformat('%1.3f');%将x轴的数据精度保留到一位小数即可
    for i = 1:length(nums)
%         text(i, nums(i), num2str(nums(i)), 'HorizontalAlignment', 'center', 'VerticalAlignment', 'bottom');
        text(i, nums(i), [num2str(nums(i)/sum(nums)*100, '%.1f'), '%'], 'HorizontalAlignment', 'center', 'VerticalAlignment', 'bottom');
        
    end
end


function Output = BinSlice(bin,range,varargin)
%输入一个Bin和范围,比如bin(30,24)
    if range(1)<range(2)
        warning("range(1)<range(2)")
        Output=app.Fixed_Length_Bin(0,range(1)+1);
        return
    end
    if bin2dec(bin)==0
        Output=app.Fixed_Length_Bin(0,range(1)+1);
        return;
    end
    if size(bin,2)<(range(1)+1)
        %bin=app.Fixed_Length_Bin(bin2dec(bin),range(1)+1);
        
        Dec_Bin=bin;
        Bin_Len=range(1)+1;
        if((range(1)+1)>=size(Dec_Bin,2))
            if nargin==2
                bin=[repmat('0',[1,range(1)+1-size(Dec_Bin,2)]) Dec_Bin];
            else
                sign=varargin{1};%符号位
                bin=[repmat(sign,[1,range(1)+1-size(Dec_Bin,2)]) Dec_Bin];
            end
        else
            bin=fliplr(Dec_Bin);
            bin=fliplr(bin(1:Bin_Len));
        end
    end
    Output=fliplr(bin);
    Output=fliplr(Output(:,(range(2)+1):1:(range(1)+1)));
end

function [InFeatureSize,OutFeatureSize] = BuildPicture(Feature_Size,Feature_Channel,Out_Channel,KernelSize,Stride)
%UNTITLED7 就是输入卷积步长，卷积核大小，然后自动推理出输入和输出图片的大小（来自Step1）
    Drop_Message=1;%将图片继续补零以匹配卷积不丢失信息
    assert(Stride<=KernelSize,"Stride must be less than kernelSize");
    OutFeatureSize=75;%无需修改，在后面自动推理出来
    assert(mod(Feature_Channel,8)==0,"输入通道必须是8的倍数");
    assert(mod(Out_Channel,8)==0,"输出通道必须是8的倍数");
    %Strdie1
    InFeatureSize=Feature_Size;
    if Stride==KernelSize
    %     OutFeatureSize=Feature_Size/KernelSize;
        if(mod(Feature_Size,KernelSize)==0)
            OutFeatureSize=Feature_Size/KernelSize;
        else
    %         fprintf("Error:kernelSize必须和FeatureSize匹配\n")
            InFeatureSize=ceil(Feature_Size/KernelSize)*KernelSize;
            OutFeatureSize=ceil(Feature_Size/KernelSize);
            warning('警告:kernelSize必须和FeatureSize没有匹配\n')
        end
    else 
        if Stride==1
        OutFeatureSize=size(1:Feature_Size-KernelSize+1,2);
        else 
            Tmp=1:Stride:Feature_Size;
            if ~Drop_Message%若不丢失信息，继续补零
                if(Tmp(end)~=Feature_Size)
                    Feature_Size=Tmp(end)+KernelSize-1%修改图片大小
                    InFeatureSize=Feature_Size;
                end
            else%若丢失信息，则修改输出图片大小
                %说明：比如3*3卷积，步长为2，输入图片322*322，
                    %对于3*3的步长为2的卷积来说是无法覆盖全部图片的（321*321->160*160,323*323->161*161)
                    %这里就是将322*322的图片变成321*321的图片，得到160*160的输出。
                    %上面就是将322*322的图片变成323*323的图片，得到161*161的输出。
                %do nothing
            end
            OutFeatureSize=size(Tmp,2)-1
        end
    end
end

function Matrix_Out = clip(Matrix_In,Lower,Upper)
%UNTITLED7 此处提供此函数的摘要
%   此处提供详细说明
Size=size(Matrix_In);
Matrix_In_Flattened=reshape(Matrix_In,1,[]);
Matrix_Out=zeros(size(Matrix_In_Flattened));
for i=1:size(Matrix_In_Flattened,2)
    if Matrix_In_Flattened(i)>Upper
        Matrix_Out(i)=Upper;
    else
        if Matrix_In_Flattened<Lower
            Matrix_Out(i)=Lower;
        else
            Matrix_Out(i)=Matrix_In_Flattened(i);
        end
    end
end
Matrix_Out=reshape(Matrix_Out,Size);
end

function [bin_sourcecode] = Complt2Sourcd(bin)
%complement2 Source code
%UNTITLED3 输入一个补码格式的二进制数，返回其十进制
%   此处提供详细说明
assert(class(bin)=="char");
bin=bin-48;
sign=bin(1);
bin=fliplr(bin(2:end));
if(sign)
    bin_sourcecode=0;%char(~bin(2:end)+48);%源码无符号位，除符号位取反+1
    for i=1:size(bin,2)
        bin_sourcecode=bin_sourcecode+2^(i-1)*(~bin(i));
    end
    bin_sourcecode=(-1)^(sign)*(bin_sourcecode+1);
else
    bin_sourcecode=0;%char(~bin(2:end)+48);%源码无符号位，除符号位取反+1
    for i=1:size(bin,2)
        bin_sourcecode=bin_sourcecode+2^(i-1)*bin(i);
    end
end
end

function [ShiftNum] = ConvQuant(Weight,Scale,ZeroPoint)
%输入权重，Scale，ZeroPoint
    % ConvQuant(rand(10,10),rand_range([0.00001,0.0001],[1,10]),rand_range([0.00001,0.0001],[1,10]))
    assert(size(size(Weight),2)==2,"输入权重需要按通道展开成二维矩阵");

Channel_Num=size(Weight,2);%获取输出通道数
%% 第一步：获取将Scale缩放到0.5~1之间ShiftNum
    Scale_RightShifted=zeros(1,Channel_Num);
    ShiftNum=zeros(1,Channel_Num);%移位数
    for i=1:Channel_Num%遍历所有通道
        while ~(Scale(i)>=0.5&&Scale(i)<1)
            Scale_RightShifted(i)=Scale(i)*2;
            ShiftNum(i)=ShiftNum(i)+1;%
        end
        Scale_RightShifted(i)=floor(Scale(i)*2^32);%继续放大32倍并取整，保留更多的精度
        %这里直接截余取整，不再四舍五入
    end
%% 第二步：

end


function [outputArg1,outputArg2] = Error_Analysis(Correct,x)
%UNTITLED3 误差分析
%   输入两个二维矩阵
% 第一步：检查最大误差和最小误差
Error=abs(Correct-x);
Fpga_Error_Max=max(Error,[],'all');
Fpga_Error_Min=min(Error,[],'all');

nums=size(Fpga_Error_Min:0.01:Fpga_Error_Max,2)
k=1;
for i=Fpga_Error_Min:0.01:Fpga_Error_Max
    
    nums(k)=sum(Error<i,'all');
    k=k+1;
end
plot(Fpga_Error_Min:0.01:Fpga_Error_Max,nums);
%然后再统计每个区间内的数据有多少
for i=size(nums,2):-1:2
    nums(i)=nums(i)-nums(i-1)
end
%% 
figure 
bar(nums);
set(gca, 'XTick', 1:length(nums), 'XTickLabel', Fpga_Error_Min:0.01:Fpga_Error_Max)
num01=sum((Correct-x)<0.1,'all');
num001=sum((Correct-x)<0.01,'all');
num0001=sum((Correct-x)<0.001,'all');
num00001=sum((Correct-x)<0.0001,'all');


%%
figure 
x=Fpga_Error_Min:0.01:Fpga_Error_Max
z=nums
y=ones(1,size(nums,2))
plot3(x, y, z, 'b-', 'LineWidth', 2)
grid on
hold on
plot3(x, y+1, z, 'b-', 'LineWidth', 2)
hold on
plot3(x+0.1, y+2, z, 'b-', 'LineWidth', 2)


%% 绘制误差热度图
figure 
errorMatrix = abs(Error);
% 绘制误差图
imagesc(errorMatrix,[Fpga_Error_Min,Fpga_Error_Max])
% 添加颜色栏
colorbar
% 设置标题和轴标签
title('Error Map')
xlabel('Column')
ylabel('Row')

%% 绘制三维误差分布
%% 
figure 
surf(Error)
view(45, 30)
lighting gouraud
shading interp
% 设置标题和轴标签
title('Error Surface')
xlabel('Column')
ylabel('Row')
zlabel('Error')
% 添加颜色栏
colorbar

end

function [bin] = Fixed_Length_Bin(app,Dec,Bin_Len)
%输入一个十进制，输出一个固定长度的二进制,不够就补零,或者补符号位
%   此处提供详细说明
Dec_Bin=reshape(dec2bin(Dec),1,[]);
if(Bin_Len>=size(Dec_Bin,2))
    if Dec>0
        bin=[reshape(dec2bin(zeros(1,Bin_Len-size(Dec_Bin,2))),1,[]) Dec_Bin];
    else
        bin=[reshape(dec2bin(ones(1,Bin_Len-size(Dec_Bin,2))),1,[]) Dec_Bin];
    end
else
    bin=fliplr(Dec_Bin);
    bin=fliplr(bin(1:Bin_Len));
end
end
function [bin] = Fixed_Length_Hex(Dec,Hex_Len)
%输入一个十进制，输出一个固定长度的十六进制,不够就补零,或者补符号位
%   此处提供详细说明
Dec_Hex=reshape(dec2hex(Dec),1,[]);
if(Hex_Len>=size(Dec_Hex,2))
    if Dec>0
        bin=[repmat('0',1,Hex_Len-size(Dec_Hex,2)) Dec_Hex];
    else
        bin=[repmat('F',1,Hex_Len-size(Dec_Hex,2)) Dec_Hex];
    end
else
    bin=fliplr(Dec_Hex);
    bin=fliplr(bin(1:Hex_Len));
end
end

function [WeightCache_Time,Compute_Time,MACs]=GemmTime(A_Size,B_Size,SA_Size,Freq,DMA_WIDTH)
    % SA[Slice,Height,Width]
    
    assert(A_Size(2)==B_Size(1));
    
    if(~exist("Freq","var"))
        Freq=200%默认200M,输入参数默认为时钟周期
        warning("频率设置为200M")
    end
    if(~exist("DMA_WIDTH",'var'))
        DMA_WIDTH=8;
    end
    CLK_CYCLE=((1/(Freq*10^6))*10^9)/10^6 ;%单位用ms来表示
    %输出矩阵的大小：
    C_Size=[A_Size(1),B_Size(2)];
    WeightCache_Time_All=(B_Size(1)*B_Size(2)/DMA_WIDTH)*CLK_CYCLE;
    WeightCache_Time=(SA_Size(1)*SA_Size(3)*A_Size(2)/DMA_WIDTH)*CLK_CYCLE
    %只需缓存第一轮也就是SLICE*WIDTH列的数据，剩下的数据可以边算边缓存
    
    Tmp1=ceil(((C_Size(1)*C_Size(2))/(SA_Size(1)*SA_Size(2)*SA_Size(3))));%脉动阵列每次可以出3*8*64个点，那么需要出多少次
    Compute_Time=Tmp1*(A_Size(2)+SA_Size(3)-1)*CLK_CYCLE;

    %感觉上面那种算法不太对
    Compute_Time=ceil(A_Size(1)/SA_Size(2))*ceil(B_Size(2)/(SA_Size(1)*SA_Size(3)))*(A_Size(2)+SA_Size(3)-1)*CLK_CYCLE
    MACs=(A_Size(1)*B_Size(2))*(A_Size(2)*2)/10^9;
    
end

function [outputArg1,outputArg2] = ImgArrange(Img,inputArg2)
%UNTITLED3 此处提供此函数的摘要
%   将2D图片重新排列成3D
%% ⭐⭐⭐⭐⭐⭐⭐创建ConvOutput的输入txt和脉动阵列输出对比（也就是脉动阵列的输出）⭐⭐⭐⭐⭐⭐⭐
%脉动阵列输出的完整一列数据
    OutFeatureSize=size(Img,1);
    OutFeature_3D_UnArranged=cell(OutFeatureSize,1);%将输出特征图每一行的数据都用元胞数组存起来，这样方便以后检查，其中每一列对应一个点的所有通道，
    %图片有多少列，每个元胞包含的数组就是多少列，行数则代表每个点的通道数
    OutFeature_3D_Arranged=cell(OutFeatureSize,1);
    OutFeature_3D_Quan=cell(OutFeatureSize,1);
    for Row=1:size(OutFeature,1)%遍历行
        RowData_2D=reshape(OutFeature(Row,:),Out_Channel,OutFeatureSize);%获取一行的数据，转化为2D格式,一共有OutFeatureSize列,没有重新排列
        OutFeature_3D_UnArranged(Row)={RowData_2D};
        Temp_Block=[];
        SA_OutPut_Row=[];
        for ColTimes=1:ceil(OutFeatureSize/8)%每8列为一组
            if(ColTimes==ceil(OutFeatureSize/8))%比如输出图片大小为14，脉动阵列一下输出图片的8列，所以需要2次才能输出图片的完整一行
                Temp_Block=RowData_2D(:,(ColTimes-1)*8+1:end);%这个if是为了处理输出图片的size不是8的倍数的情况，这里补零是因为可能拿不到8列数据，为了和前面的8列进行拼接就需要补零
                zeros_padding=zeros(size(Temp_Block,1),8-size(Temp_Block,2));%注意这里补零只是将图片的列size补零成8的倍数，而没有进行通道的补零，这与硬件实现需要区分开，硬件实现则是对通道进行了补零
                Temp_Block=[Temp_Block,zeros_padding];%TODO：以后还要在这里补充和硬件对应的通道补零
                SA_OutPut_Row=[SA_OutPut_Row;Temp_Block];%这里进行数组拼接
            else
                Temp_Block=RowData_2D(:,(ColTimes-1)*8+1:ColTimes*8);
                SA_OutPut_Row=[SA_OutPut_Row;Temp_Block];
            end
        end
        OutFeature_3D_Arranged(Row)={SA_OutPut_Row};
    end
end

function OutPut = lp_loss(pred, tgt, p,reduction)
    if reduction == "NONE"
        OutPut= mean(sum(abs(pred - tgt).^(p),1),"all");%.pow(p).sum(1).mean()
    else
        OutPut= mean(abs((pred - tgt)).^(p),"all");%.abs().pow(p).mean()
    end
end

function [outputArg1] = rand_range(range,size)
    assert(range(2)>=range(1),"range(2) must bigger than range(1)");
    outputArg1=range(1) + (range(2)-range(1))*rand(size);
end

function [] = Read_DMA(ReadLength,varargin)
    if(size(varargin,2)==0)
        fprintf("Status = XAxiDma_SimpleTransfer(&AxiDma,(UINTPTR)CONV_RESULT_BASE_ADDR,%d, XAXIDMA_DEVICE_TO_DMA);\n" + ...
        "if (Status != XST_SUCCESS) {//启动DMA接收\n" + ...
        "return XST_FAILURE;\n" + ...
        "}\n" + ...
        "while ((!RxDone) && !Error) {" + ...
        "}\n",ReadLength);
    else
        assert(string(class(varargin{1}))=="string");
        fprintf("Status = XAxiDma_SimpleTransfer(&AxiDma,(UINTPTR)(%s),%d, XAXIDMA_DEVICE_TO_DMA);\n" + ...
        "if (Status != XST_SUCCESS) {//启动DMA接收\n" + ...
        "return XST_FAILURE;\n" + ...
        "}\n" + ...
        "while ((!RxDone) && !Error) {" + ...
        "}\n",varargin{1},ReadLength);
    end
end

function [] = ReadWrite_DMA(app,fid,TxAddr,TxLength,RxAddr,RxLength)
    assert((string(class(RxAddr))=="string")&&(string(class(TxAddr))=="string"));
        fprintf(fid,"Status = XAxiDma_SimpleTransfer(&AxiDma,(UINTPTR)(%s),%d, XAXIDMA_DEVICE_TO_DMA);\n" + ...
        "if (Status != XST_SUCCESS) {//启动DMA接收\n" + ...
        "return XST_FAILURE;\n" + ...
        "}\n" + ...
        "Status = XAxiDma_SimpleTransfer(&AxiDma,(UINTPTR)(%s),%d, XAXIDMA_DMA_TO_DEVICE);\n" + ...
        "if (Status != XST_SUCCESS) {//启动dMA发送\n" + ...
        "return XST_FAILURE;\n" + ...
        "}\n" + ...
        "while ((!TxDone||!RxDone) && !Error) {" + ...
        "}\n" + ...
        "TxDone=0;RxDone=0;\n",RxAddr,RxLength,TxAddr,TxLength);

end

%% 局部函数
%一种很直接的策略，给定资源量，决定需要拆分的次数
function [Method]=Split_Method(MatrixB,Bram_Depth,Slice,Height,Width)
%Bram_Depth:Bram的深度，位宽按8来算，深度1，只能存1个点
%这里的MatrixB_Size为权重矩阵的大小，移位现在使用脉动阵列在计算，所以都用矩阵来描述
assert(numel(size(MatrixB))==2,"矩阵B必须是一个二维矩阵");
[Row,Col]=size(MatrixB);
Supported_Max_Col=Slice*Width*floor(Bram_Depth/Row);%对于当前矩阵来说，计算时可缓存的最大列数
%策略一：按列进行拆分
    %只要能存够一列，那么就可以按列进行拆分，否则执行策略二，按行进行拆分
    if Row<=Bram_Depth
        Method.Str="按列拆分"
        Split_Times=ceil(Col/Supported_Max_Col);
        Section=[];
        for i=1:Split_Times%从哪开始从哪结束
            Start=(i-1)*(Supported_Max_Col)+1;
            if i*(Supported_Max_Col)>=Col
                End=Col;
            else
                End=i*(Supported_Max_Col);
            end
            Section=[Section;[Start,End]];
        end
        Method.Section=Section;
    end
end


function [Bias,Scale,Shift] = Gen_Rand_Data(app,MatrixRow,MatrixCol)%生成随机数据
%generate rand data
Bias=zeros(1,MatrixCol);%32bit  [1bit符号位][7bit 移位值][24bit实际值]
Scale=zeros(1,MatrixCol);
Shift=zeros(1,MatrixCol);


%创建随机的Scale
for i=1:MatrixCol
    Scale_bin=app.Fixed_Length_Bin(randi([1,2^24-1],1),32);
    Scale(i)=bin2dec(Scale_bin);
end
%创建随机的Bias
for i=1:1:MatrixCol
    Bias_24=reshape(dec2bin(randi([0,1],[1,24])),1,[]);%低24位
    Bias_Dec=dec2bin(randi([0,16],[1]));%一个随机的移位值
    Bias_7=[reshape(dec2bin(zeros(1,7-size(Bias_Dec,2))),1,[]) Bias_Dec];%创建7bit数据，不够7bit就补零
    assert(bin2dec(Bias_Dec)<17)
    Bias_1=reshape(dec2bin(randi([0,1],[1,1])),1,[]);%符号位
    Bias(i)=bin2dec([Bias_1 Bias_7 Bias_24]);
    
end
end

%% 模拟卷积量化计算
function Quat_Out=ConvQuant_Compute(Scale,Bias,Shift,Matrix,ZeroPoint)
    
    Sz_Matrix=size(Matrix);
    assert(Sz_Matrix(1)==1);%输入一个向量
    
    AddZero=zeros(Sz_Matrix);
%第一步：先加Bias
    %对Bias进行处理
    Bias_Bin_All=dec2bin(Bias);

    
    Bias_Result=zeros(3,Sz_Matrix(2));
    Bias_Result(1,:)=Matrix(1,:)*2^16;
    Scale_Result=zeros(3,Sz_Matrix(2));
    DataShift_Result=zeros(3,Sz_Matrix(2));
    for i=1:Sz_Matrix(2)%遍历所有列
        %先加Bias-----------------------------------------------------------
        Bias_Bin=Bias_Bin_All(i,:);
        Bias_Shift=bin2dec(BinSlice(Bias_Bin,[30,24]));%Bias的移位值
        Bias_Shift;
        Bias_Tmp=[repmat(Bias_Bin(1),[1,8+Bias_Shift]),BinSlice(Bias_Bin,[23,0]),repmat('0',[1,16-Bias_Shift])];        
        BiasAdd=bin2dec(Bias_Tmp)+Matrix(i)*2^16;%需要注意这里的计算都是补码计算,所以出来的可能是49bit，所以还是得截后48bit
        BiasAdd=Complt2Sourcd(app.Fixed_Length_Bin(BiasAdd,48));%截取后48位取补码
            %需要注意的是，BiasAdd是有符号数并且是补码，所以在做有符号乘法之前需要处理一下补码的有符号计算
                %不能简单的用BiasAdd=bin2dec(app.Fixed_Length_Bin(BiasAdd,48))来计算。        
            %将计算数据存下来
            Bias_Result(2,i)=bin2dec(Bias_Tmp);
            Bias_Result(3,i)=BiasAdd;

        %加完Bias，再乘Scale  48bit*32bit-----------------------------------

        
        ScaleMul=Scale(i)*BiasAdd;%结果为79~48的截位
        sign=char((ScaleMul<0)+48);
        if ScaleMul<intmin('int64')
            %dec2hex无法处理int64以外的数据，只能手动转二进制
            error("dec2hex无法处理int64以外的数据，只能手动转二进制\n:dec is to big");
        end
        ScaleMul=Complt2Sourcd(BinSlice(dec2bin(ScaleMul),[79,48],sign));%补码转源码
            %同样地，将计算数据存下来
            Scale_Result(1,i)=BiasAdd;
            Scale_Result(2,i)=Scale(i);
            Scale_Result(3,i)=ScaleMul;


        %乘完Scale再shift---------------------------------------------------
        ScaleMul_Bin=dec2bin(ScaleMul);
        sign=char(49-(ScaleMul>=0));%这里就是判断有没有符号，是不是负数
        Shift(i);
            %又叒出bug了，需要注意4bit无法表示-8，因为1000可以是8也可以是-8.。。麻了
        DataShift=[BinSlice(ScaleMul_Bin,[size(ScaleMul_Bin,2)-1,Shift(i)])];%如果shift=1，那么就去掉最后那一位即可
            %还有一个四舍五入的过程，
        if bin2dec(DataShift)~=0
            DataShift=[repmat(sign,[1,32-size(DataShift,2)]),DataShift];%这里不如直接补成32bit得了
        end
        
        DataShift=Complt2Sourcd([DataShift(1) BinSlice(DataShift,[15,1],sign)])+(DataShift(end)-48);
            DataShift_Result(1,i)=ScaleMul;
            DataShift_Result(2,i)=Shift(i);
            DataShift_Result(3,i)=DataShift;
        
        
        %最后加zeropoint
        AddZero(i)=DataShift+ZeroPoint;
        if AddZero(i)<0
            AddZero(i)=0;
        else 
            if AddZero(i)>255
                AddZero(i)=255;
            end
        end
    end
    Quat_Out={Bias_Result;Scale_Result;DataShift_Result;AddZero};

end

function RETURN = Step9_LayerNorm(inputArg1,inputArg2)
    RETURN.LayerNorm_FP_Compute=@LayerNorm_FP_Compute;
end

function [] = LayerNorm_FP_Compute(Matrix_In,Gamma,Beta,Sin,Zin,Sout,Zout)
%layernorm 的浮点计算
% (X-mu_x)*Gamma/sqrt((sigma_x)^2+eps)+Beta
%先计算均值
Matrix_In=rand(197,384);
Gamma=rand(1);
Beta=rand(1);

K=3;
Sin=((max(Matrix_In,[],"all")-min(Matrix_In,[],"all")/(2^8-1)));
bitWidth=8;
Zin=clip(round(min(Matrix_In,[],"all")/((max(Matrix_In,[],"all")-min(Matrix_In,[],"all")/(2^bitWidth-1)))),0,2^bitWidth-1);

M1=sum(Matrix_In,2);%沿第二个维度计算累加和
M2=sum(Matrix_In.^2,2);%沿第二个维度计算平方和
%% 论文的量化方法
scale8=Sin;
scale4=scale8/2;
scale2=scale4/2;
scale1=scale2/2;
zero_point=Zin;
scale_mask = ones(1,size(Matrix_In,2));
for i=1:size(Matrix_In,2)%遍历列
    data=Matrix_In(:,i);
    data_q1=clip(round(data/scale1+Zin),0,255)-zero_point*scale1;
    data_q2=clip(round(data/scale2+Zin),0,255)-zero_point*scale2;
    data_q4=clip(round(data/scale4+Zin),0,255)-zero_point*scale4;
    data_q8=clip(round(data/scale8+Zin),0,255)-zero_point*scale8;
    score1 = lp_loss(data, data_q1, 2.0, "all");
    score2 = lp_loss(data, data_q2, 2.0, "all");
    score4 = lp_loss(data, data_q4, 2.0, "all");
    score8 = lp_loss(data, data_q8, 2.0, "all");
    score = [score1, score2, score4, score8];
    
    [row,col]=find(score==min(score));

    scale_mask(i) =scale_mask(i)* 2^(col-1);
end
Scale_Ptf = scale1 .* scale_mask;%获得加入PTF后的Scale
%% 拿到Ptf后再用这个去模拟硬件量化计算

%% 浮点LayerNorm计算结果
mu_x=mean(Matrix_In,2);%计算均值
sigma_x=var(Matrix_In,0,2);%计算方差   需要和pytorch对比归一化因子时n-1还是n


LayerNorm_Fp=((Matrix_In-mu_x)*Gamma)./(sqrt(sigma_x))+Beta;

%误差评估：浮点算法，浮点量化，硬件计算三种对比
%对layerNorm进行量化

%PTF误差用L2范数衡量


end


function []= Write_DMA(app,fid,SendLength,varargin)
    if(size(varargin,2)==1)
        assert(string(class(varargin{1}))=="string");
        fprintf(fid,"Status = XAxiDma_SimpleTransfer(&AxiDma,(UINTPTR)(%s),%d, XAXIDMA_DMA_TO_DEVICE);\n" + ...
        "if (Status != XST_SUCCESS) {//启动dMA发送\n" + ...
        "return XST_FAILURE;\n" + ...
        "}\n" + ...
        "while ((!TxDone) && !Error) {" + ...
        "}\n" + ...
        "TxDone=0;\n",varargin{1},SendLength);
    else
    fprintf(fid,"Status = XAxiDma_SimpleTransfer(&AxiDma,(UINTPTR)WEIGHT_BASE_ADDR,%d, XAXIDMA_DMA_TO_DEVICE);\n" + ...
        "if (Status != XST_SUCCESS) {//启动dMA发送\n" + ...
        "return XST_FAILURE;\n" + ...
        "}\n" + ...
        "while ((!TxDone) && !Error) {" + ...
        "}\n" + ...
        "TxDone=0;\n",SendLength);
    end
end


%%
        
        function [] = Img2Col(app)
            %% 生成每一轮的输出数据

% load('保存的matlab数据\第一版随机输入数据.mat','Frature_In');
load('matlab.mat')
fid=fopen('MatrixA输入.txt','w');
%% ============开关（只改这里）===========
Compare_By_Row=1;%逐行对比，如果关闭就是直接生成全部的对比数据
Compare_Row=14;%需要修改
%% =======================================


Row_Base_Addr=0;
if ~Compare_By_Row
    Compare_Row=Out_Row;
end
%% =======================================
Present_Row=1;
for Out_Row_Cnt=1:Compare_Row
    %每次开始前需要初始化
    Out_Row_Cnt
    Kernel_Base_Addr=1;
    Kernel_Addr=1;
    Out_Col_Lefted=Out_Col;
    Window_Col_BaseAddr=0;%滑动窗口列 基地址
    if Compare_By_Row
        fprintf(fid,'===========OutPut Row %d==========\n',Out_Row_Cnt);%低位第一个点，高位第二个点，
    end
    for Out_Col_Cnt=1:ceil(Out_Col/Height)
        for Out_Channel_Cnt=1:ceil(Out_Channel/Height)
            for Window_Row_Cnt=1:KernelSize
%                              fprintf('========================New Col================================\n')
                for Window_Col_Cnt=1:KernelSize
                    %                 fprintf('========Row-[%d] Col-[%d]=========\n',Window_Row_Cnt-1,(Window_Col_Cnt-1)/3)
                    for InChannel_Process_Cnt=1:Feature_Channel/8
                        for SA_Cnt=1:8

                            OutPut=Feature_In(Window_Row_Cnt+Row_Base_Addr,Kernel_Addr:Kernel_Addr+8-1);
                            OutPut_HexFormat=fliplr(OutPut);%以十六进制的格式输出，第一个通道的点放[7:0],第二个通道的点放[15:8]...
%                             OutPut_HexFormat=reshape(dec2hex(OutPut_HexFormat)',1,[]);%这里记得转置一下
                            Kernel_Addr=Kernel_Addr+Stride*Feature_Channel;
                            if Compare_By_Row
                                if Compare_Row==Present_Row
%                                     fprintf(fid,'%016s\n',lower(OutPut_HexFormat));%低位第一个点，高位第二个点，
                                      fprintf(fid,'%02x%02x%02x%02x%02x%02x%02x%02x\n',OutPut_HexFormat);
                                end
                            else
                                  fprintf(fid,'%02x%02x%02x%02x%02x%02x%02x%02x\n',OutPut_HexFormat);
%                                 fprintf(fid,'%016s\n',lower(OutPut_HexFormat));%低位第一个点，高位第二个点，
                            end
                     
                            if SA_Cnt==Out_Col_Lefted
                                break
                            end
                        end
                        Kernel_Addr=Kernel_Base_Addr+InChannel_Process_Cnt*8+(Window_Col_Cnt-1)*Feature_Channel;
                    end

                    Kernel_Addr=Kernel_Base_Addr+Window_Col_Cnt*Feature_Channel;
                end
                Kernel_Addr=Kernel_Base_Addr;%从新的一行开始，卷积核地址复位
            end
            
        end
        Out_Col_Lefted=Out_Col_Lefted-8;
        Kernel_Base_Addr=Kernel_Base_Addr+8*Feature_Channel*Stride;
        Kernel_Addr=Kernel_Base_Addr;
    end
    Row_Base_Addr=Row_Base_Addr+Stride;%更新行基地址
    Present_Row=Present_Row+1;
end
fclose(fid);


%% 测试工具

addr=[16:31]*224*32/8;
addr=addr';

Test=[152	255	174	160	139	250	112	158]%aa(4,:)
reshape(dec2hex(fliplr(Test))',1,[])

        end


        function [] = COnvSim(app)
            load("matlab.mat")
OutFeature=zeros(Out_Row,Out_Col*Out_Channel);
i=1;
j=1;
%% 硬件卷积输出（标准的图片格式），数据未量化
for row=1:Stride:In_Row-KernelSize+1%遍历行
    for col=1:Stride:In_Col-KernelSize+1%遍历列
        Window=reshape(Feature_In(row:row+KernelSize-1,(col-1)*Feature_Channel+1:(col+KernelSize-1)*Feature_Channel)',1,[]);%计算一个滑窗对应的全部输出点
        OutFeature(i,j:j+Out_Channel-1)=Window*WeightMatrix;
        j=j+Out_Channel;
    end
    i=i+1;
    j=1;
end
%% ⭐⭐⭐⭐⭐⭐⭐创建ConvOutput的输入txt和脉动阵列输出对比（也就是脉动阵列的输出）⭐⭐⭐⭐⭐⭐⭐
%脉动阵列输出的完整一列数据
OutFeature_3D_UnArranged=cell(OutFeatureSize,1);%将输出特征图每一行的数据都用元胞数组存起来，这样方便以后检查，其中每一列对应一个点的所有通道，
%图片有多少列，每个元胞包含的数组就是多少列，行数则代表每个点的通道数
OutFeature_3D_Arranged=cell(OutFeatureSize,1);
OutFeature_3D_Quan=cell(OutFeatureSize,1);
for Row=1:size(OutFeature,1)%遍历行
    RowData_2D=reshape(OutFeature(Row,:),Out_Channel,OutFeatureSize);%获取一行的数据，转化为2D格式,一共有OutFeatureSize列,没有重新排列
    OutFeature_3D_UnArranged(Row)={RowData_2D};
    Temp_Block=[];
    SA_OutPut_Row=[];
    for ColTimes=1:ceil(OutFeatureSize/8)%每8列为一组
        if(ColTimes==ceil(OutFeatureSize/8))%比如输出图片大小为14，脉动阵列一下输出图片的8列，所以需要2次才能输出图片的完整一行
            Temp_Block=RowData_2D(:,(ColTimes-1)*8+1:end);%这个if是为了处理输出图片的size不是8的倍数的情况，这里补零是因为可能拿不到8列数据，为了和前面的8列进行拼接就需要补零
            zeros_padding=zeros(size(Temp_Block,1),8-size(Temp_Block,2));%注意这里补零只是将图片的列size补零成8的倍数，而没有进行通道的补零，这与硬件实现需要区分开，硬件实现则是对通道进行了补零
            Temp_Block=[Temp_Block,zeros_padding];%TODO：以后还要在这里补充和硬件对应的通道补零
            SA_OutPut_Row=[SA_OutPut_Row;Temp_Block];%这里进行数组拼接
        else
            Temp_Block=RowData_2D(:,(ColTimes-1)*8+1:ColTimes*8);
            SA_OutPut_Row=[SA_OutPut_Row;Temp_Block];
        end
    end
    OutFeature_3D_Arranged(Row)={SA_OutPut_Row};
end

%获取量化后的卷积输出数据
    %阶段一：简单截位,只用于上板测试
for i=1:size(OutFeature_3D_Arranged,1)%遍历行
    OriginData=OutFeature_3D_Arranged{i};
    OriginData_Cut=fliplr(dec2hex(OriginData));
    OriginData_Cut=fliplr(OriginData_Cut(:,1:2));%直接截位取低8bit
    OriginData_Cut=reshape(hex2dec(OriginData_Cut),size(OutFeature_3D_Arranged{1}));
    OutFeature_3D_Quan{i}=OriginData_Cut;
end
if 0
    Valid_Sign=1;%数据Valid有效信号
    Valid_Sign=['FF','7F','3F','1F','0F','07','03','01'];
    fid=fopen("阵列输出仿真数据.txt","w");
    for i=1:size(OutFeature_3D_Arranged,1)%遍历行
        BlockData=OutFeature_3D_Arranged{i};
        for j=1:size(OutFeature_3D_Arranged{1,1},1)
            QuanData=fliplr(dec2hex(BlockData(j,:)));
            QuanData=fliplr(QuanData(:,1:2));
            for Print_Times=1:8
                if Print_Times==8
                    fprintf(fid,"%02s\n",QuanData(8-Print_Times+1,:));
                else
                    fprintf(fid,"%02s",QuanData(8-Print_Times+1,:));
                end
            end
            
        end
    end
    fclose(fid);
end
%生成输出对比文件
    %第一步：将矩阵展平，得到连续的数据，模拟连续的数据流
%     for i=1:size(OutFeature_3D_Quan,1)%遍历行
%         OutFeature_3D_Quan_Flatten=reshape(OutFeature_3D_Quan{i},1,[]);
%         for j=1:8:
%     end
%  reshape(dec2hex(fliplr(reshape([137;153;29;229;187;239;49;59],1,[])))',1,[])
%创建脉动阵列输出的随机仿真数据
if 0
    ConvOutput=randi([0,255],ceil(OutFeatureSize/8)*OutFeatureSize*8*32,8);
    fid=fopen("阵列输出仿真数据.txt","w");
    for i=1:size(ConvOutput,1)
        fprintf(fid,"%02x%02x%02x%02x%02x%02x%02x%02x\n",ConvOutput(i,8),ConvOutput(i,7),ConvOutput(i,6),ConvOutput(i,5),ConvOutput(i,4),ConvOutput(i,3),ConvOutput(i,2),ConvOutput(i,1));
    end
end
% a=fliplr(dec2hex([1000,300]))
% a=fliplr(a(1:2,1:2))
% fprintf("%02s\n%02s\n",a(1,:),a(2,:))
%% 构建随机矩阵，一下选1列的8行数据进行输出(For 卢浩）
if 0
    Matrix_B=zeros(163,40);
    Row=size(Matrix_B,1);
    Col=size(Matrix_B,2);
    Matrix_B=randi([0,255],Row,Col)
    Matrix_B=[Matrix_B;zeros(5,40)];
    fid_raw_W=fopen('MatrixData.txt','w');
    for i=1:8:size(Matrix_B,1)
        for j=1:size(Matrix_B,2)
            
            Matrix_Flattened=Matrix_B(i:i+7,j);%fliplr(');
            fprintf(fid_raw_W,'%02x%02x%02x%02x%02x%02x%02x%02x\n',Matrix_Flattened(8),Matrix_Flattened(7),Matrix_Flattened(6),Matrix_Flattened(5),Matrix_Flattened(4),Matrix_Flattened(3),Matrix_Flattened(2),Matrix_Flattened(1));%低位第一个点，高位第二个点，
        end
    end
    fclose(fid_raw_W);
    dec2hex(Matrix_B(1:8,1));
end
%% 检查中间数据
%需要输入连续的三个数据，输出这三个数据对应的行列
if 0
    data1=12826;
    data2=12782;
    data3=13425;
    for i=1:size(OutFeature,1)
        for j=1:size(OutFeature,2)-2
            if OutFeature(i,j)==data1&&OutFeature(i,j+1)==data2&&OutFeature(i,j+2)==data3           
                [i,j]
                break
            end
        end
    end
end
%% 全仿输出数据对比
if 0
    Check_Row=14;
    Check_All=1;
    fid_raw_W=fopen('阵列输出数据.txt','w');
    OutNum=8;%设置一下出多少个点
    %数据重排，按通道优先格式输出
    for i=1:Out_Row %遍历输出图片的行
        fprintf(fid_raw_W,'======================Row%d======================\n',i);
        Matrix_Flattened=OutFeature_3D_UnArranged{i};
        if i==Check_Row||(Check_All)
            for j=1:Out_Col%遍历列
                for k=1:(OutNum):Out_Channel-7%遍历输出通道
%                     fprintf(fid_raw_W,'%08x%08x%08x%08x%08x%08x%08x%08x\n',Matrix_Flattened(k+7,j),Matrix_Flattened(k+6,j),Matrix_Flattened(k+5,j),Matrix_Flattened(k+4,j),Matrix_Flattened(k+3,j),Matrix_Flattened(k+2,j),Matrix_Flattened(k+1,j),Matrix_Flattened(k,j));%低位第一个点，高位第二个点，
                      fprintf(fid_raw_W,'%08x%08x\n',Matrix_Flattened(k+1,j),Matrix_Flattened(k,j));%低位第一个点，高位第二个点，
                end
            end
        end
    end
    fclose(fid_raw_W);
end

if 0
    %检查第一个点的卷积计算
    reshape(Feature_In(1:KernelSize,1:(Feature_Channel*KernelSize))',1,[])*WeightMatrix(:,1)
end

        end
        
        function results = onBoardTest(app)
            load("matlab.mat")
Picture_Flattened=reshape(Feature_In',1,[]);
if WEIGHT_VERSION==1
    WeightMatrix_Flattened=reshape(WeightMatrix,1,[]);
    if ~exist("Scale","var"),
    warning("如果还没有生成量化参数，需要先生成随机的量化参数，更新matlab.mat后再重新生成上板bin文件\n开始生成随机量化参数\n");
    [Bias,Scale,Shift]=Step8_ConvQuant().Gen_Rand_Data(OutFeatureSize^2,Out_Channel);
    save("matlab.mat");
    end
    Scale_Flattened=reshape(Scale,1,[]);
    Bias_Flattened=reshape(Bias,1,[]);
    Shift_Flattened=reshape(Shift,1,[]);
else
    %这里先默认权重的列数为8的倍数，或者输出图片的通道为8的倍数
    WeightMatrix_Flattened=[];
    for i=8:8:size(WeightMatrix,2)
        WeightMatrix_Flattened=[WeightMatrix_Flattened,reshape(WeightMatrix(:,i-7:i)',1,[])];
    end
end
%% 单独生成图片和权重
if 0
    fid=fopen("PictureIn.bin",'w');
    fwrite(fid,Picture_Flattened,"uint8");
    fclose(fid);
    
    fid=fopen("Weight.bin",'w');
    fwrite(fid,WeightMatrix_Flattened,"int8");
    fclose(fid);

%     Picture=reshape(randi(15,[40,40*32*2]),1,[]);
%     fid=fopen("Picture.bin",'w');
%     fwrite(fid,Picture,"int8");
%     fclose(fid);
%     
% Picture=reshape(randi(15,[40,40*32*2]),1,[]);
%     fid=fopen("Picture.txt",'w');
%     for i=1:size(Picture,2)
% fprintf(fid,"%x,\n",Picture(i));
%     end
%     fclose(fid);
end
%% 也可以将图片和权重拼起来（前期测试的方案，权重放图片前面)
fid=fopen("OnBoardTest.bin",'w');
fwrite(fid,WeightMatrix_Flattened,"uint8");
fwrite(fid,Bias_Flattened,"uint32");%uint8和int8生成的bin文件还有区别，存在一种可能，2341694147用int32无法表示，因为int32还要1bit的符号位。。。
fwrite(fid,Scale_Flattened,"uint32");
fwrite(fid,Shift_Flattened,"uint32");
fwrite(fid,Picture_Flattened,"uint8");
fclose(fid);
%% 发送数据量和收回数据量计算
SendPicture_Len=size(Feature_In,1)*size(Feature_In,2);
SendWeight_Len=size(WeightMatrix,1)*size(WeightMatrix,2);
SendQuantFactor_Len=Out_Channel*3*4;%Scale，Shift，Zp，每个参数4字节，每个通道都有一个Factor
ReceivePicture_Len=Out_Col*Out_Row*Out_Channel;
[SendPicture_Len,SendWeight_Len,SendPicture_Len+SendWeight_Len,ReceivePicture_Len];
fprintf("SendLength=%d;\n",SendPicture_Len+SendWeight_Len+SendQuantFactor_Len)
fprintf("ReceiveLength=%d;\n",ReceivePicture_Len)


        end
        
        function [] = ThesisPlot(app)
            % 定义属性的名称和数据
subplot(1, 2, 1);

Cfg=[
"(1,8,8)"  
"(2,8,8)"   
"(3,8,8)"   
"(3,8,64)"  
"(4,8,32)"
"(4,8,64)"
] 
Data=[
    [7,1,4,26,8];
    [9,1,5,41,11];
    [11,1,6,55,13];
    [47,3,24,55,66];
    [33,2,17,56,46];
    [62,4,30,70,87];
    ]
%用Struct创建一个有序字典
orderedDict = struct('Cfg', Cfg, 'Data', Data);
%Cfg=dictionary(Cfg,Data);%创建一个字典---dictionary 只能在2023a以上的版本使用
Cfg_nums=size(Cfg,1);


b=bar(Data,'FaceColor','flat');
% for i=1:5
%     b.CData(i,:)=rand(1,3);
% end
% 设置 x 轴标签
xticks(1:Cfg_nums);
xticklabels(Cfg);
% 设置 y 轴标签
ylabel('Utilization(%)');
% 标题
title('A:The resource utilization and energy efficiency of accelerators of different sizes');
% 网格线
grid on;
% 添加图例


% 创建折线图
hold on;
Dly_Tiny=[
    97.320
    49.543
    33.637
    7.457
    9.288
    6.725
]
Dly_Small=[
   346.670
    175.010
    117.837
    21.111
    26.146
    16.776
]
Dly_Base=[
    1301.488
    654.045
    438.319
    67.379
    90.181
    52.170
]
Gops=[25.144
    50.034
    74.659
    485.679
    362.873
    626.884
    ]%就是每个加速器在Vit-Base下的Gops
Power=[
  4.509
  5.002 
  5.398 
  10.881 
  8.5 
  13.225%12.0250
];
Power=Power-1.2;
Eff=Gops./(Power-2.2)
yyaxis right;  % 使用右边坐标轴
ylabel('Energy efficiency(Gops/w)');
color = rand(1,3);  % 随机生成RGB颜色
plot(Eff, '-.o', 'LineWidth', 3,'Color',color);
legend('LUT', 'LUTRAM', 'FF', 'BRAM', 'DSP',"Gops/w");


subplot(1, 2, 2);

color = rand(1,3);  % 随机生成RGB颜色
plot(Dly_Tiny, '-.o', 'LineWidth', 3,'Color',color);
hold on
color = rand(1,3);  % 随机生成RGB颜色
plot(Dly_Small, '-.o', 'LineWidth', 3,'Color',color);
hold on
color = rand(1,3);  % 随机生成RGB颜色
plot(Dly_Base, '-.o', 'LineWidth', 3,'Color',color);

% legend("Vit-Base","Vit-Small","Vit-Tiny");
xticks(1:Cfg_nums);
xticklabels(Cfg);
ylabel('Latency(ms)');

yyaxis right;  % 使用右边坐标轴
ylabel('Throughput（Gop/s)');
hold on
color = rand(1,3);  % 随机生成RGB颜色
plot(Gops, '-.p', 'LineWidth', 3,'Color',color);
legend("Vit-Base","Vit-Small","Vit-Tiny","Throughput")
title("B:Performance of accelerator with different sizes")
%% 卷积3*3~16*16的计算时间统计

Strides = [];
times = [];
figure
for KernelSize = 3:16
    Strides = [1:KernelSize,zeros(1,16-KernelSize)];
    times = [times;[randi(10,[1,KernelSize]),zeros(1,16-KernelSize)]];
    bar3(times);
    hold on
end


figure
for KernelSize = 3:16
    Strides = 1:KernelSize;
    times = randi(10,[1,KernelSize]);
    color = rand(1,3);  % 随机生成RGB颜色
    plot3([Strides; times],'Color',color);
     
    hold on
end
grid on

xlabel('Kernel Size');
ylabel('Stride');
zlabel('Time');
title('Computational Time for Different Kernel Sizes and Strides');

grid on;

%% 画4*4的大图用于展示对不同尺度和不同大小卷积的支持
clear
figure;
Freq=200;
CLK_CYCLE=((1/(Freq*10^6))*10^9)/10^6 ;%时钟周期：单位用ms来表示
Slice=4;
Height=8;
Width=64;
DMA_WDITH=24;
for i = 1:14
    subplot(4, 4, i);
    KernelSize=i+2;
    times=[];
    Gops=[];
    OutSize=[];
    Feature_Channel=8;
    Out_Channel=768;
    
    for Strides = 1:KernelSize;
        [InSize,OutFeatureSize]=BuildPicture(480,Feature_Channel,Out_Channel,KernelSize,Strides);
      
        OutSize=[OutSize,OutFeatureSize];%获取输入图片大小图片大小
        
        
        A_Size=[OutFeatureSize^2,KernelSize^2*Feature_Channel];
        B_Size=[KernelSize^2*Feature_Channel,Out_Channel];
        %% 卷积计算
        [Conv_Cache,Conv_Compute,Conv_MACS]=GemmTime(A_Size,B_Size,[Slice,Height,Width],Freq,DMA_WDITH);
        Conv_Gops=Conv_MACS/(Conv_Compute+Conv_Cache)*10^3%B矩阵缓存时间时间不应该考虑，因为在计算的时候可以同时缓存权重
        Total_Time=Conv_Compute+Conv_Cache;
        Total_Macs=Conv_MACS;

        times = [times,Conv_Gops];
        Gops=[Gops,Total_Macs];
    end
    color = rand(1,3);  % 随机生成RGB颜色
    plot(1:KernelSize, times,'-p','LineWidth',1,'Color',color);
    ylabel("Gops")
    hold on
    color = rand(1,3);  % 随机生成RGB颜色
    yyaxis right;
%     semilogy(1:KernelSize, Gops, 'r-o');
%     hold on

%     gca.YAxis(2).Scale = 'log';  % 设置右边坐标轴为对数刻度
    plot(1:KernelSize, Gops,'-o','LineWidth',1,'Color',color);
    %xlabel("kernelsize");
    ylabel("Macs")
    title("KernelSize="+string(KernelSize)+";Stride=1~"+string(KernelSize),'FontWeight','bold')
    grid on
end
legend('Gops','MAC');

        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: Button
        function ButtonPushed(app, event)
            % 在App Designer的回调函数中使用以下代码

            % 获取图片文件的路径
            [filename, pathname] = uigetfile({'*.jpg;*.png;*.bmp', 'Image Files (*.jpg, *.png, *.bmp)'}, '选择图片');
            
            % 检查用户是否选择了图片文件
            if isequal(filename, 0)
                % 用户取消了选择，不执行任何操作
                return;
            end
            
            % 构建完整的图片文件路径
            imagePath = fullfile(pathname, filename);
            
            % 读取图片
            imageData = imread(imagePath);
            
            % 在App Designer中的Image组件中展示图片
            app.Image.ImageSource = imageData;
        end

        % Callback function
        function Step1ButtonPushed(app, event)
            % 创建新的模态对话框窗口
            % 创建新的模态对话框窗口
            configDialog = uifigure('Name', '配置界面', 'WindowStyle', 'modal');
            configDialog.Position = [100 100 300 200]; % 设置窗口位置和大小
            
            % 添加卷积核大小的文本输入框
            kernelSizeLabel = uilabel(configDialog, 'Text', '卷积核大小:');
            kernelSizeLabel.Position = [20 120 100 20];
            
            kernelSizeField = uitextarea(configDialog);
            kernelSizeField.Position = [130 120 100 20];
            
            kernelSizeField.Value
            % 添加保存按钮
            saveButton = uibutton(configDialog, 'Text', '保存', 'Position', [100 50 100 30]);
            saveButton.ButtonPushedFcn =@(~,~)saveButtonCallback(app,configDialog,kernelSizeField.Value{1});
            
            % 等待用户关闭对话框
            uiwait(configDialog);
        end

        % Button pushed function: Save_Cfg
        function Create_mat(app, event)
            %% 第一步： 生成8x输入通道的测试数据，包含32输入通道的图片和32输出通道的卷积权重
                % 图片大小，步长等相关信息需要手动配置
            ProjDir=app.SimWorkSpace.Text%"E:\Transformer\Matlab\main\Tests\SA_3D\SA_1_8_8\CNN_K3_S1_IC8_Oc32";%修改
            if exist(ProjDir,'dir')
                warning("文件夹已经存在！！")
                cd(ProjDir)
            else
                mkdir(ProjDir)
                cd(ProjDir)
            end
            Drop_Message=1;%将图片继续补零以匹配卷积不丢失信息
            WEIGHT_VERSION=1;%权重缓存模块的版本，可选1（V1），2（V2，已失效）
            Feature_Size=str2double(app.Feature_Size.Value{1})%224;%图片大小224*224
            Feature_Channel=str2double(app.Feature_Channel.Value{1});%图片通道
            Out_Channel=str2double(app.Out_Channel.Value{1});%输出图片通道，要修改
            
            Stride=str2double(app.Stride.Value{1});%要修改
            KernelSize=str2double(app.appKernelSize.Value{1});%要修改
            assert(Stride<=KernelSize,"Stride must be less than kernelSize");
            %脉动阵列配置
            Slice=str2double(app.Tile.Value{1});
            Height=8;%不要改
            Width=str2double(app.Width.Value{1});
            HeadNums=12;%记得修改HeadNums，Tiny=3,Small=6,base=12
            
            %Compute_InChannel=Height;%每次计算的输出通道，
            %Compute_OutChannel=Slice*Width;%每次计算的输出列数，先别动
            
            OutFeatureSize=75;%无需修改，在后面自动推理出来
            assert(mod(Feature_Channel,8)==0,"输入通道必须是8的倍数");
            assert(mod(Out_Channel,8)==0,"输出通道必须是8的倍数");
            %Strdie1
            if Stride==KernelSize
            %     OutFeatureSize=Feature_Size/KernelSize;
                if(mod(Feature_Size,KernelSize)==0)
                    OutFeatureSize=Feature_Size/KernelSize;
                else
            %         fprintf("Error:kernelSize必须和FeatureSize匹配\n")
                    error('Error:kernelSize必须和FeatureSize匹配\n')
                end
            else 
                if Stride==1
                OutFeatureSize=size(1:Feature_Size-KernelSize+1,2);
            
                else 
                    Tmp=1:Stride:Feature_Size;
                    if ~Drop_Message%若不丢失信息，继续补零
                        
                        if(Tmp(end)~=Feature_Size)
                            Feature_Size=Tmp(end)+KernelSize-1%修改图片大小
                        end
                    else%若丢失信息，则修改输出图片大小
                        %说明：比如3*3卷积，步长为2，输入图片322*322，
                            %对于3*3的步长为2的卷积来说是无法覆盖全部图片的（321*321->160*160,323*323->161*161)
                            %这里就是将322*322的图片变成321*321的图片，得到160*160的输出。
                            %上面就是将322*322的图片变成323*323的图片，得到161*161的输出。
                        %do nothing
                    end
                    OutFeatureSize=size(Tmp,2)-1
                end
            end


            Out_Col=OutFeatureSize;
            Out_Row=OutFeatureSize;
            Out_Col_Lefted=Out_Col;
            In_Col=Feature_Size;
            In_Row=Feature_Size;
            Feature_In=zeros(Feature_Size,Feature_Size*Feature_Channel);
            Feature_Row=zeros(1,Feature_Size);
            
            for row=1:Feature_Size
                Row_Data=rand(1,Feature_Size*Feature_Channel)*5;
                Row_Data_Outer=(Row_Data>255)*255;%将大于255的数变成255
                Row_Data_Inner=Row_Data.*(Row_Data<=255);%将小于255的数保留
                Row_Data=Row_Data_Outer+Row_Data_Inner;
                Row_Data=round(Row_Data);
                Feature_In(row,:)=Row_Data;
            end
            

            %% 开始构建权重数据
            WeightMatrix=zeros(Feature_Channel*KernelSize*KernelSize,Out_Channel);%%构建权重矩阵
            WeightMatrix_Row=zeros(1,Out_Channel);
            
            
            Row=size(WeightMatrix,1);
            Col=size(WeightMatrix,2);
            for row=1:Row%遍历矩阵所有行
                Row_Data=rand(1,Col)*5;
                Row_Data_Outer=(Row_Data>255)*255;%将大于255的数变成255
                Row_Data_Inner=Row_Data.*(Row_Data<=255);%将小于255的数保留
                Row_Data=Row_Data_Outer+Row_Data_Inner;
                Row_Data=round(Row_Data);
                WeightMatrix(row,:)=Row_Data;
            end

           
            save('matlab.mat', '-regexp', '^(?!app$|event$).');%不保存名为app和event的对象


            if 0%读取pytorch生成的bin文件
                %先读取权重
                fid=fopen("WeightIn.bin")
                WeightMatrix=fread(fid,size(WeightMatrix),"int8")
                fclose(fid);
                %读取量化参数
                fid=fopen("WeightIn.bin")
                Weight_Quant_Data=fread(fid)
                fclose(fid);
                Quant_Data=Weight_Quant_Data(size(WeightMatrix,1)*size(WeightMatrix,2)+1:end);
            
                fid=fopen("Bias_Scale_Shift.bin",'w')
                fwrite(fid,Quant_Data,'uint8');%这里必须按uint8来写
                fclose(fid);
                fid=fopen("Bias_Scale_Shift.bin",'r')
                Bias_Scale_Shift=fread(fid,'uint32')
                fclose(fid);
                Scale=zeros(1,Out_Channel);
                Shift=zeros(1,Out_Channel);
                Bias=zeros(1,Out_Channel);
                for i=1:Out_Channel
                    Bias(i)=Bias_Scale_Shift(i)
                end
                for i=Out_Channel+1:2*Out_Channel
                    Scale(i-Out_Channel)=Bias_Scale_Shift(i)
                end
                for i=2*Out_Channel+1:3*Out_Channel
                    Shift(i-Out_Channel*2)=Bias_Scale_Shift(i)
                end
                save("matlab.mat")
                %再读图片
                fid=fopen("PictureIn.bin","r");
                Feature_In=fread(fid,fliplr(size(Feature_In)),"uint8")';
                fclose(fid);


            end

        end

        % Button pushed function: WorkSpaceButton
        function SimWorkSpaceButtonPushed(app, event)
            % 选择目录
            selectedDir = uigetdir();
            if selectedDir ~= 0
                app.SimWorkSpace.Text=selectedDir
            end
        end

        % Button pushed function: WorkSpaceButton_2
        function WorkSpaceButton_2Pushed(app, event)
            selectedDir = uigetdir();
            if selectedDir ~= 0
                app.TextArea_7Label_8.Text=selectedDir
            end
        end

        % Value changed function: Feature_Channel
        function Feature_ChannelValueChanged(app, event)
            value = app.Feature_Channel.Value;
            app.InChannel.Text=value
        end

        % Button pushed function: Create_Bin_Picture
        function Create_Bin_PictureButtonPushed(app, event)
            load("matlab.mat")
            Picture_Flattened=reshape(Feature_In',1,[]);
            fid=fopen("Picture.bin",'w');
            fwrite(fid,Picture_Flattened,"uint8");
            fclose(fid);
        end

        % Button pushed function: Create_Bin_Weight
        function Create_Bin_WeightButtonPushed(app, event)
            load("matlab.mat");
            if WEIGHT_VERSION==1
                WeightMatrix_Flattened=reshape(WeightMatrix,1,[]);
            else
                %这里先默认权重的列数为8的倍数，或者输出图片的通道为8的倍数
                WeightMatrix_Flattened=[];
                for i=8:8:size(WeightMatrix,2)
                    WeightMatrix_Flattened=[WeightMatrix_Flattened,reshape(WeightMatrix(:,i-7:i)',1,[])];
                end
            end
            fid=fopen("Weight.bin",'w');
            fwrite(fid,WeightMatrix_Flattened,"uint8");
            fclose(fid);
        end

        % Button pushed function: Create_Bin_Quant
        function Create_Bin_QuantButtonPushed(app, event)
            %生成量化bin文件
            load("matlab.mat");
            if ~exist("Scale","var"),
                warning("如果还没有生成量化参数，需要先生成随机的量化参数，更新matlab.mat后再重新生成上板bin文件\n开始生成随机量化参数\n");
                [Bias,Scale,Shift]=app.Gen_Rand_Data(OutFeatureSize^2,Out_Channel);
                save('matlab.mat', '-regexp', '^(?!app$|event$).');
            end
            Scale_Flattened=reshape(Scale,1,[]);
            Bias_Flattened=reshape(Bias,1,[]);
            Shift_Flattened=reshape(Shift,1,[]);
            %创建bin文件
            fid=fopen("Quant.bin",'w');
            fwrite(fid,Bias_Flattened,"uint32");%uint8和int8生成的bin文件还有区别，存在一种可能，2341694147用int32无法表示，因为int32还要1bit的符号位。。。
            fwrite(fid,Scale_Flattened,"uint32");
            fwrite(fid,Shift_Flattened,"uint32");
            fclose(fid);
        end

        % Button pushed function: Create_txt_Picture
        function Create_txt_PictureButtonPushed(app, event)
            load("matlab.mat")

            
            fid_raw_W=fopen('Picture.txt','w');
            Matrix_Flattened=reshape(Feature_In',1,[]);%展平
            Shape=size(Matrix_Flattened);
             for i=8:8:Shape(2)
              %通道按从低往高排列，比如第一个通道的点放最低位
              fprintf(fid_raw_W,'%02x%02x%02x%02x%02x%02x%02x%02x\n',Matrix_Flattened(i),Matrix_Flattened(i-1),Matrix_Flattened(i-2),Matrix_Flattened(i-3),Matrix_Flattened(i-4),Matrix_Flattened(i-5),Matrix_Flattened(i-6),Matrix_Flattened(i-7));%低位第一个点，高位第二个点，
             end
            fclose(fid_raw_W);
        end

        % Button pushed function: Create_txt_Weight
        function Create_txt_WeightButtonPushed(app, event)
            %% 生成权重仿真txt
            %权重也按通道优先展开,列优先存储
            %先将权重矩阵展平
            load("matlab.mat");
            if WEIGHT_VERSION==1
                Matrix_Flattened=reshape(WeightMatrix,1,[]);%按列优先展平
                fid_raw_W=fopen('Weight.txt','w');
                Shape=size(Matrix_Flattened);
                for i=8:8:Shape(2)
                    %通道按从低往高排列，比如第一个通道的点放最低位
                    fprintf(fid_raw_W,'%02x%02x%02x%02x%02x%02x%02x%02x\n',Matrix_Flattened(i),Matrix_Flattened(i-1),Matrix_Flattened(i-2),Matrix_Flattened(i-3),Matrix_Flattened(i-4),Matrix_Flattened(i-5),Matrix_Flattened(i-6),Matrix_Flattened(i-7));%低位第一个点，高位第二个点，
                end
                fclose(fid_raw_W);
            else% 权重格式版本2，
                fid_raw_W=fopen('WeightData.txt','w');
                Shape=size(WeightMatrix);
                for i=8:8:Shape(2)
                    for j=1:Shape(1)
                        Matrix_Flattened=WeightMatrix(j,i-7:i);
                        %通道按从低往高排列，比如第一个通道的点放最低位
                        fprintf(fid_raw_W,'%02x%02x%02x%02x%02x%02x%02x%02x\n',Matrix_Flattened(8),Matrix_Flattened(7),Matrix_Flattened(6),Matrix_Flattened(5),Matrix_Flattened(4),Matrix_Flattened(3),Matrix_Flattened(2),Matrix_Flattened(1));%低位第一个点，高位第二个点，
                    end
                end
                fclose(fid_raw_W);
            
            end
        end

        % Button pushed function: Create_txt_Quant
        function Create_txt_QuantButtonPushed(app, event)
        load("matlab.mat");
        fid=fopen("Scale_Bias_Shift.txt","w");
        MatrixCol=Out_Channel;
        %创建随机shift
        for i=1:1:MatrixCol
            Shift(i)=bin2dec(app.Fixed_Length_Bin(randi([0,4],1),32));
            
        end
        %生成txt文件
        for i=1:2:MatrixCol
            fprintf(fid,"%08s%08s\n",dec2hex(Bias(i+1)),dec2hex(Bias(i)));
        end
        for i=1:2:MatrixCol
            fprintf(fid,"%08s%08s\n",dec2hex(Scale(i+1)),dec2hex(Scale(i)));
        end
        for i=1:2:MatrixCol
            fprintf(fid,"%08s%08s\n",dec2hex(Shift(i+1)),dec2hex(Shift(i)));
        end
        fclose all;
        
        end

        % Button pushed function: Create_Bin_All
        function Create_Bin_AllButtonPushed(app, event)
            %% 也可以将图片和权重拼起来（前期测试的方案，权重放图片前面)
            load("matlab.mat")
            Picture_Flattened=reshape(Feature_In',1,[]);
            WeightMatrix_Flattened=reshape(WeightMatrix,1,[]);
            Scale_Flattened=reshape(Scale,1,[]);
            Bias_Flattened=reshape(Bias,1,[]);
            Shift_Flattened=reshape(Shift,1,[]);
            
            fid=fopen("AllData.bin",'w');
            fwrite(fid,WeightMatrix_Flattened,"uint8");
            fwrite(fid,Bias_Flattened,"uint32");%uint8和int8生成的bin文件还有区别，存在一种可能，2341694147用int32无法表示，因为int32还要1bit的符号位。。。
            fwrite(fid,Scale_Flattened,"uint32");
            fwrite(fid,Shift_Flattened,"uint32");
            fwrite(fid,Picture_Flattened,"uint8");
            fclose(fid);
        end

        % Button pushed function: Button_2
        function Button_2Pushed(app, event)
            %%生成仿真验证指令
                        % addpath("funs\");
                %开关-------------------------------------------
            fid=fopen("SimInsts.txt","w");
            Matrix2Img=1;%将矩阵转化为通道优先的3D图片
            ConvTest=1;%如果测试卷积，则生成卷积指令，否则生成MM指令
                %-----------------------------------------------
            load("matlab.mat")
            io_Stride=Stride;
            io_KernelSize=KernelSize;
            io_Window_Size=KernelSize*Feature_Channel/(Height);
            if io_Window_Size<1
                io_Window_Size=0;%这里考虑的情况是：存在计算输出通道大于网络输出通道的可能
                                    %比如8*8*8的脉动阵列，一下能算64个输出通道
            else
                io_Window_Size=ceil(io_Window_Size);
            end
            io_InFeature_Size=Feature_Size;
            io_InFeature_Channel=Feature_Channel;
            io_OutFeature_Channel=Out_Channel;
            io_OutFeature_Size=OutFeatureSize;
            io_OutCol_Count_Times=OutFeatureSize/(Height);%输出列计数器：比如输出8*8*8阵列，可以同时处理8个输出点的64通道
            if io_OutCol_Count_Times<1
                io_OutCol_Count_Times=0;
            else
                io_OutCol_Count_Times=ceil(io_OutCol_Count_Times);
            end
            io_InCol_Count_Times=Feature_Channel*Feature_Size/Height;%目前还是要求输入通道必须是8的倍数
            io_OutFeature_Channel_Count_Times=ceil(Out_Channel/(Slice*Width));
            io_Sliding_Size=Feature_Channel*Stride/Height;
            io_OutRow_Count_Times=OutFeatureSize;
            QuantInstru_zeroIn=59;%待修改
            %% io输入参数
            fprintf(fid,".QuantInstru_zeroIn            (%d),\n",QuantInstru_zeroIn                         )
            fprintf(fid,".Img2Col_Stride                        (%d),\n",io_Stride                        )
            fprintf(fid,".Img2Col_Kernel_Size                   (%d),\n",io_KernelSize                    )
            fprintf(fid,".Img2Col_Window_Size                   (%d),\n",io_Window_Size                   )
            fprintf(fid,".Img2Col_InFeature_Size                (%d),\n",io_InFeature_Size                )
            fprintf(fid,".Img2Col_InFeature_Channel             (%d),\n",io_InFeature_Channel             )
            fprintf(fid,".Img2Col_OutFeature_Channel            (%d),\n",io_OutFeature_Channel            )
            fprintf(fid,".Img2Col_OutFeature_Size               (%d),\n",io_OutFeature_Size               )
            fprintf(fid,".Img2Col_Sliding_Size                  (%d),\n",io_Sliding_Size)
            fprintf(fid,".Img2Col_OutCol_Count_Times            (%d),\n",io_OutCol_Count_Times            )
            fprintf(fid,".Img2Col_InCol_Count_Times             (%d),\n",io_InCol_Count_Times             )
            fprintf(fid,".Img2Col_OutRow_Count_Times            (%d),\n",io_OutRow_Count_Times            )
            fprintf(fid,".Img2Col_OutFeature_Channel_Count_Times(%d),\n",ceil(io_OutFeature_Channel_Count_Times))
            fprintf(fid,"//===================================================\n")
            
            %% 权重对应指令,全部统一用矩阵表示
            [Row,Col]=size(WeightMatrix);
            io_WeightMatrix_Row=Row;
            io_WeightMatrix_Col=Col;
            io_OutMatrix_Row=Out_Row*Out_Col;
            io_OutMatrix_Col=Out_Col;
            fprintf(fid,".Img2Col_WeightMatrix_Row                 (%d),\n",io_WeightMatrix_Row)
            fprintf(fid,".Img2Col_OutMatrix_Col                    (%d),\n",io_WeightMatrix_Col)
            fprintf(fid,".Img2Col_OutMatrix_Row                    (%d),\n",io_OutMatrix_Row)
            fprintf(fid,"//===================================================\n")
            fclose(fid);

%             [filename, pathname] = uigetfile('*.txt', 'SimInsts');
                % 使用fileread函数读取文本文件的内容
                fileContent = fileread(fullfile("SimInsts.txt"));
                % 将文本内容分配给TextArea组件的String属性
                app.TextArea_12.Value = fileContent;
        end

        % Button pushed function: Button_3
        function Button_3Pushed(app, event)
                        %% 生成上板测试数据+指令
            %Instru1:两个5bit拼起来
            load("matlab.mat");
            filename="OnBoardInst.txt";
            fid=fopen(filename,"w");
            ConvTest=1;
            Matrix2Img=1;
            io_Stride=Stride;
            io_KernelSize=KernelSize;
            io_Window_Size=KernelSize*Feature_Channel/(Height);
            if io_Window_Size<1
                io_Window_Size=0;%这里考虑的情况是：存在计算输出通道大于网络输出通道的可能
                                    %比如8*8*8的脉动阵列，一下能算64个输出通道
            else
                io_Window_Size=ceil(io_Window_Size);
            end
            io_InFeature_Size=Feature_Size;
            io_InFeature_Channel=Feature_Channel;
            io_OutFeature_Channel=Out_Channel;
            io_OutFeature_Size=OutFeatureSize;
            io_OutCol_Count_Times=OutFeatureSize/(Height);%输出列计数器：比如输出8*8*8阵列，可以同时处理8个输出点的64通道
            if io_OutCol_Count_Times<1
                io_OutCol_Count_Times=0;
            else
                io_OutCol_Count_Times=ceil(io_OutCol_Count_Times);
            end
            io_InCol_Count_Times=Feature_Channel*Feature_Size/Height;%目前还是要求输入通道必须是8的倍数
            io_OutFeature_Channel_Count_Times=ceil(Out_Channel/(Slice*Width));
            io_Sliding_Size=Feature_Channel*Stride/Height;
            io_OutRow_Count_Times=OutFeatureSize;
            QuantInstru_zeroIn=59;%待修改
            [Row,Col]=size(WeightMatrix);
            io_WeightMatrix_Row=Row;
            io_WeightMatrix_Col=Col;
            io_OutMatrix_Row=Out_Row*Out_Col;
            io_OutMatrix_Col=Out_Col;


            if ConvTest
            Stride_Bin=sprintf("%05s",dec2bin(io_Stride));
            KernelSize_Bin=sprintf("%05s",dec2bin(io_KernelSize));
            fprintf(fid,"Write_Lite(REG_Table_BASE_ADDR,0x8,0x%s);\n",dec2hex(bin2dec([KernelSize_Bin+Stride_Bin])))  
            %Instru2：两个16bit拼起来
            fprintf(fid,"Write_Lite(REG_Table_BASE_ADDR,0xc,0x%s%04s);\n",dec2hex(io_InFeature_Size),dec2hex(io_Window_Size))
            
            %Instru3：两个16bit拼起来
            fprintf(fid,"Write_Lite(REG_Table_BASE_ADDR,0x10,0x%s%04s);\n",dec2hex(io_OutFeature_Channel),dec2hex(io_InFeature_Channel))
            
            %Instru4：16+13bit
            Sliding_Size=sprintf("%013s",dec2bin(io_Sliding_Size));
            OutFeature_Size=sprintf("%016s",dec2bin(io_OutFeature_Size));
            IMG2COL_INSTRU4=dec2hex(bin2dec([Sliding_Size+OutFeature_Size]));
            fprintf(fid,"Write_Lite(REG_Table_BASE_ADDR,0x14,0x%s);\n",IMG2COL_INSTRU4)
            
            %Instru5：两个16bit拼起来
            fprintf(fid,"Write_Lite(REG_Table_BASE_ADDR,0x18,0x%s%04s);\n",dec2hex(io_InCol_Count_Times),dec2hex(io_OutCol_Count_Times))
            
            %Instru6：两个16bit拼起来
            fprintf(fid,"Write_Lite(REG_Table_BASE_ADDR,0x1c,0x%s%04s);\n",dec2hex(io_OutFeature_Channel_Count_Times),dec2hex(io_OutRow_Count_Times	))
            
            %Instru7：1个16bit
            fprintf(fid,"Write_Lite(REG_Table_BASE_ADDR,0x20,0x%s);\n",dec2hex(io_WeightMatrix_Row))
            
            %Instru8:20+12
            % ReceivePicture_Len=Out_Col*Out_Row*Out_Channel;
            if Matrix2Img
                OutMatrix_Row=sprintf("%020s",dec2bin(io_OutMatrix_Row));%图片行数
                OutMatrix_Col=sprintf("%012s",dec2bin(io_OutMatrix_Col));%图片列数
                IMG2COL_INSTRU8=dec2hex(bin2dec([OutMatrix_Row+OutMatrix_Col]));
                fprintf(fid,"Write_Lite(REG_Table_BASE_ADDR,0x24,0x%s);\n",IMG2COL_INSTRU8)
            else
                error("已失效");
                OutMatrix_Col=sprintf("%012s",dec2bin(Out_Channel));%图片列数
                %行数是8的倍数,假如输出图片大小为14*14，那么收回去的数据的行数应该是ceil(14*14/8)*8
                OutMatrix_Row=sprintf("%012s",dec2bin(ceil(Out_Row*Out_Col/8)));%图片行
                IMG2COL_INSTRU8=dec2hex(bin2dec([OutMatrix_Row+OutMatrix_Col]));
                fprintf(fid,"Write_Lite(REG_Table_BASE_ADDR,0x24,0x%s);\n",IMG2COL_INSTRU8)
                ReceivePicture_Len=Out_Channel*ceil(Out_Row*Out_Col/8)*8;
            end
            else
                ["在Step7中实现矩阵测试"]
            end
            
            % QUANTREG1:0x2c,量化因子
            fprintf(fid,"Write_Lite(REG_Table_BASE_ADDR,0x2C,0x%s);\n",dec2hex(QuantInstru_zeroIn))
            %% 收发数据量计算
            %单位：字节
            SendPicture_Len=size(Feature_In,1)*size(Feature_In,2);
            SendWeight_Len=size(WeightMatrix,1)*size(WeightMatrix,2);
            SendQuantFactor_Len=Out_Channel*3*4;%Scale，Shift，Zp，每个参数4字节，每个通道都有一个Factor
            ReceivePicture_Len=Out_Col*Out_Row*Out_Channel;
            [SendPicture_Len,SendWeight_Len,SendPicture_Len+SendWeight_Len,ReceivePicture_Len];
            fprintf(fid,"SendLength=%d;\n",SendPicture_Len+SendWeight_Len+SendQuantFactor_Len)
            fprintf(fid,"ReceiveLength=%d;\n",ReceivePicture_Len)
            
            %% 地址配置---权重-量化参数-图片(这样子编排顺序是为了仿真的时候方便)
            MEM_BASE_ADDR           =0x01000000;
            WEIGHT_BASE_ADDR		=(MEM_BASE_ADDR + 0x00100000);
            QUANT_BASE_ADDR         =(WEIGHT_BASE_ADDR + SendWeight_Len);
            PICTURE_BASE_ADDR		=(QUANT_BASE_ADDR + SendQuantFactor_Len);
            CONV_RESULT_BASE_ADDR	=(MEM_BASE_ADDR + 0x10000000);
            fprintf(fid,"//bin文件写入地址:%d bin文件大小：%d\n",WEIGHT_BASE_ADDR,SendPicture_Len+SendWeight_Len+SendQuantFactor_Len);
            
            %% 仿真switch时间控制
            SendWeight_Len/8;
            SendQuantFactor_Len/8;
            
            %% 卷积计算：先发权重，再发量化参数，最后发送图片
            %输入switch
            MASK_SWITCH_WEIGHT  =0;%代表的bit位置，第0bit，第1bit。。。
            MASK_SWITCH_QUANT   =1;
            MASK_SWITCH_IMG2COL =2;
            MASK_SWITCH_LAYERNORM=3;
            MASK_SWITCH_SOFTTMAX=4;
            
            
            %0x4 控制寄存器
            %第一步：启动权重缓存：,配置下面这些开关：
                %配置方法：比如要算卷积，那么需要启动的模块有权重缓存模块，量化模块，Img2Col模块和DataArrange模块共4个模块
                %首先需要启动权重缓存模块，InSwitch_Weight=1,在启动完权重缓存模块的调试还需要启动DataArrange模块
            
                %然后向权重缓存模块发送权重数据
                %发送完权重数据后，需要将输入数据通路切换到量化模块，继续缓存量化数据
                %量化数据缓存完后，即可将输入数据通路切换到Img2Col，开始启动卷积计算即可
            Start=1;
            InSwitch_Weight=1;
            InSwitch_Img2col=0;
            InSwitch_Quant=0;
            InSwitch_Layernorm=0;
            InSwitch_Gemm=0;
            
            QuantSwicth_LayerNorm=0;
            QuantSwicth_Softmax=0;
            QuantSwitch_DataArrange=1;
            
            OutSwitch_LayerNorm=0;
            OutSwitch_Softmax=0;
            OutSwitch_DataArrange=1;
            
            SwitchCtrl=[InSwitch_Gemm,InSwitch_Layernorm,InSwitch_Img2col,InSwitch_Quant,InSwitch_Weight];
            QuantSwitch=[0,QuantSwicth_Softmax,QuantSwicth_LayerNorm,QuantSwitch_DataArrange];
            OutSwitchCtrl=[0,QuantSwicth_Softmax,QuantSwicth_LayerNorm,QuantSwitch_DataArrange];
            Ctrl=[OutSwitchCtrl,QuantSwitch,SwitchCtrl,0,0,Start];
            
            fprintf(fid,"Write_Lite(REG_Table_BASE_ADDR,0x4,0x%s);\n//启动权重缓存并且启动DataArrange分路\n",dec2hex(bin2dec(char(Ctrl+48))))
            app.Write_DMA(fid,SendWeight_Len,string(WEIGHT_BASE_ADDR));%发送权重数据
            
            %发完权重后继续发量化参数
            Start=1;
            InSwitch_Weight=0;
            InSwitch_Img2col=0;
            InSwitch_Quant=1;
            InSwitch_Layernorm=0;
            InSwitch_Gemm=0;
            
            SwitchCtrl=[InSwitch_Gemm,InSwitch_Layernorm,InSwitch_Img2col,InSwitch_Quant,InSwitch_Weight];
            QuantSwitch=[0,QuantSwicth_Softmax,QuantSwicth_LayerNorm,QuantSwitch_DataArrange];
            OutSwitchCtrl=[0,QuantSwicth_Softmax,QuantSwicth_LayerNorm,QuantSwitch_DataArrange];
            Ctrl=[OutSwitchCtrl,QuantSwitch,SwitchCtrl,0,0,Start];
            fprintf(fid,"Write_Lite(REG_Table_BASE_ADDR,0x4,0x%s);//发送量化参数\n",dec2hex(bin2dec(char(Ctrl+48))))
            app.Write_DMA(fid,SendQuantFactor_Len,string(QUANT_BASE_ADDR));%发送量化参数
            
            %最后发送图片数据并且接收计算结果
            Start=1;
            InSwitch_Weight=0;
            InSwitch_Img2col=1;
            InSwitch_Quant=0;
            InSwitch_Layernorm=0;
            InSwitch_Gemm=0;
            
            SwitchCtrl=[InSwitch_Gemm,InSwitch_Layernorm,InSwitch_Img2col,InSwitch_Quant,InSwitch_Weight];
            Ctrl=[OutSwitchCtrl,QuantSwitch,SwitchCtrl,0,0,Start];
            fprintf(fid,"Write_Lite(REG_Table_BASE_ADDR,0x4,0x%s);//发送图片数据并接收计算结果\n",dec2hex(bin2dec(char(Ctrl+48))))
            app.ReadWrite_DMA(fid,string(PICTURE_BASE_ADDR),SendPicture_Len,string(CONV_RESULT_BASE_ADDR),ReceivePicture_Len);
            fclose(fid);
            fileContent = fileread(fullfile(filename));
            % 将文本内容分配给TextArea组件的String属性
            app.TextArea_13.Value = fileContent;
    
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Image = uiimage(app.UIFigure);
            app.Button = uibutton(app.UIFigure, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Text = '选择图片';
            app.Out_Channel = uitextarea(app.UIFigure);
            app.Out_Channel.Value = {'32'};
            app.Stride = uitextarea(app.UIFigure);
            app.Stride.Value = {'1'};
            app.Label_4 = uilabel(app.UIFigure);
            app.Label_4.Text = '输入通道';
            app.Label_5 = uilabel(app.UIFigure);
            app.Label_5.Text = '输出通道';
            app.Label_6 = uilabel(app.UIFigure);
            app.Label_6.Text = '步长';
            app.appKernelSize = uitextarea(app.UIFigure);
            app.appKernelSize.Value = {'3'};
            app.Label_7 = uilabel(app.UIFigure);
            app.Label_7.Text = '卷积核大小';
            app.TextArea_7Label = uilabel(app.UIFigure);
            app.TextArea_7Label.Text = '卷积配置';
            app.TextArea_7Label_2 = uilabel(app.UIFigure);
            app.TextArea_7Label_2.Text = '图片配置';
            app.Feature_Size = uitextarea(app.UIFigure);
            app.Feature_Size.Value = {'224'};
            app.Label_8 = uilabel(app.UIFigure);
            app.Label_8.Text = '图片大小';
            app.TextArea_7Label_3 = uilabel(app.UIFigure);
            app.TextArea_7Label_3.Text = '脉动阵列';
            app.Tile = uitextarea(app.UIFigure);
            app.Tile.Value = {'1'};
            app.Width = uitextarea(app.UIFigure);
            app.Width.Value = {'8'};
            app.TextArea_7Label_4 = uilabel(app.UIFigure);
            app.TextArea_7Label_4.Text = 'Tile';
            app.TextArea_7Label_5 = uilabel(app.UIFigure);
            app.TextArea_7Label_5.Text = 'Height';
            app.TextArea_7Label_6 = uilabel(app.UIFigure);
            app.TextArea_7Label_6.Text = 'Width';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.Tab = uitab(app.TabGroup);
            app.Tab.Title = '第一步：生成验证数据';
            app.Create_Bin_Picture = uibutton(app.Tab, 'push');
            app.Create_Bin_Picture.ButtonPushedFcn = createCallbackFcn(app, @Create_Bin_PictureButtonPushed, true);
            app.Create_Bin_Picture.Text = '创建图片Bin文件';
            app.Create_Bin_Weight = uibutton(app.Tab, 'push');
            app.Create_Bin_Weight.ButtonPushedFcn = createCallbackFcn(app, @Create_Bin_WeightButtonPushed, true);
            app.Create_Bin_Weight.Text = '创建权重Bin文件';
            app.Create_txt_Picture = uibutton(app.Tab, 'push');
            app.Create_txt_Picture.ButtonPushedFcn = createCallbackFcn(app, @Create_txt_PictureButtonPushed, true);
            app.Create_txt_Picture.Text = '创建图片仿真文件';
            app.Create_txt_Weight = uibutton(app.Tab, 'push');
            app.Create_txt_Weight.ButtonPushedFcn = createCallbackFcn(app, @Create_txt_WeightButtonPushed, true);
            app.Create_txt_Weight.Text = '创建权重仿真文件';
            app.Create_Bin_Quant = uibutton(app.Tab, 'push');
            app.Create_Bin_Quant.ButtonPushedFcn = createCallbackFcn(app, @Create_Bin_QuantButtonPushed, true);
            app.Create_Bin_Quant.Text = '创建量化Bin文件';
            app.Create_txt_Quant = uibutton(app.Tab, 'push');
            app.Create_txt_Quant.ButtonPushedFcn = createCallbackFcn(app, @Create_txt_QuantButtonPushed, true);
            app.Create_txt_Quant.Text = '创建量化仿真文件';
            app.Create_Bin_All = uibutton(app.Tab, 'push');
            app.Create_Bin_All.ButtonPushedFcn = createCallbackFcn(app, @Create_Bin_AllButtonPushed, true);
            app.Create_Bin_All.Text = {'一键生成'; '全部文件'; '并拼接'};
            app.Tab_2 = uitab(app.TabGroup);
            app.Tab_2.Title = '第二步：生成仿真指令';
            app.TextArea_12 = uitextarea(app.Tab_2);
            app.Button_2 = uibutton(app.Tab_2, 'push');
            app.Button_2.ButtonPushedFcn = createCallbackFcn(app, @Button_2Pushed, true);
            app.Button_2.Text = {'开始生成'; '仿真指令'};
            app.Tab_4 = uitab(app.TabGroup);
            app.Tab_4.Title = '第第三步：生成实测指令';
            app.TextArea_13 = uitextarea(app.Tab_4);
            app.Button_3 = uibutton(app.Tab_4, 'push');
            app.Button_3.ButtonPushedFcn = createCallbackFcn(app, @Button_3Pushed, true);
            app.Button_3.Text = {'开始生成'; '实测指令'};
            app.Tab_5 = uitab(app.TabGroup);
            app.Tab_5.Title = '第四步：实测结果逐字节验证';
            app.BinButton = uibutton(app.Tab_5, 'push');
            app.BinButton.Text = '选择Bin文件';
            app.Button_4 = uibutton(app.Tab_5, 'push');
            app.Button_4.Text = '开始对比';
            app.TextArea_14 = uitextarea(app.Tab_5);
            app.TextArea_14.Value = {'对比结果'};
            app.Tab_6 = uitab(app.TabGroup);
            app.Tab_6.Title = '第五步：误差分析';
            app.Button_6 = uibutton(app.Tab_6, 'push');
            app.Button_6.Text = '选择浮点数据';
            app.Button_8 = uibutton(app.Tab_6, 'push');
            app.Button_8.Text = '选择定点数据';
            app.Button_9 = uibutton(app.Tab_6, 'push');
            app.Button_9.Text = '误差分析';
            app.Tab_7 = uitab(app.TabGroup);
            app.Tab_7.Title = '第六步：加速器性能分析';
            app.Button_10 = uibutton(app.Tab_7, 'push');
            app.Button_10.Text = '性能分析';
            app.TextArea_15 = uitextarea(app.Tab_7);
            app.TextArea_15.Value = {'加速器性能'};
            app.WorkSpaceButton_2 = uibutton(app.UIFigure, 'push');
            app.WorkSpaceButton_2.ButtonPushedFcn = createCallbackFcn(app, @WorkSpaceButton_2Pushed, true);
            app.WorkSpaceButton_2.Text = '实测WorkSpace';
            app.WorkSpaceButton = uibutton(app.UIFigure, 'push');
            app.WorkSpaceButton.ButtonPushedFcn = createCallbackFcn(app, @SimWorkSpaceButtonPushed, true);
            app.WorkSpaceButton.Text = '仿真WorkSpace';
            app.Save_Cfg = uibutton(app.UIFigure, 'push');
            app.Save_Cfg.ButtonPushedFcn = createCallbackFcn(app, @Create_mat, true);
            app.Save_Cfg.Text = '保存配置信息';
            app.SimWorkSpace = uilabel(app.UIFigure);
            app.SimWorkSpace.Text = 'E:\Transformer\Matlab\main\App\Test';
            app.TextArea_7Label_8 = uilabel(app.UIFigure);
            app.TextArea_7Label_8.Text = 'E:\Transformer\Matlab\main\App\Test';
            app.Feature_Channel = uitextarea(app.UIFigure);
            app.Feature_Channel.ValueChangedFcn = createCallbackFcn(app, @Feature_ChannelValueChanged, true);
            app.Feature_Channel.Value = {'8'};
            app.Label_10 = uilabel(app.UIFigure);
            app.Label_10.Text = '图片通道';
            app.InChannel = uilabel(app.UIFigure);
            app.InChannel.Text = '8';
            app.TextArea_7Label_9 = uilabel(app.UIFigure);
            app.TextArea_7Label_9.Text = '8';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end